classdef MouseControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        TextArea   matlab.ui.control.TextArea
        Label_2    matlab.ui.control.Label
        Button_2   matlab.ui.control.Button
        EditField  matlab.ui.control.NumericEditField
        Label      matlab.ui.control.Label
        Button     matlab.ui.control.Button
        UIAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Flag % 判断是否定制了参数
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %按下开始按钮
            % 从model.mat文件加载模型
            loadedModel = load('RFmodel.mat');
            % 重新获取TreeBagger对象
            model = loadedModel.model;
            % 调用javaRobot类
            javaaddpath('E:\桌面\srtp眼电\代码们\鼠标控制\jdk-17\bin')  %文件绝对路径
            import java.awt.Robot;
            import java.awt.event.*;
            import java.awt.event.InputEvent;
            % 创建Robot对象
            robot = Robot();

            % 读取数据三个文件（可能之后做动态实验时这一部分代码要改）
            load("mix4_channel1.mat");
            load("mix4_channel2.mat");
            eog=hampel([mix4_channel1,mix4_channel2]);
            mix_label=[4 1 4 3 2 3 1 3 1 1 4 5];

            % 设置一些读取数据的参数
            frame_T=10;        %在数据上框选子数据的频率
            frame_move=50;     %节选框每次移动的距离
            frame_length=700;  %框的大小
            frame_energy=0.1;    %眼动能量达到此阈值才开始启动模型（范围是0-1）
            frame_interval=1.5;%识别得到一个指令信号后，为了防止同一个指令动作被反复识别，间隔多长时间重新开始裁剪数据
            
            mouse_T=10;        %鼠标每秒移动的次数
            mouse_move=10;     %鼠标每次移动的距离
            
            level = 5; % 小波变换的层数，可根据实际情况调整
            wname = 'db4'; % 小波类型，可根据实际情况选择
            alpha=1.5;%阈值倍数

            % 绘制波形到UIAxes上
            plot(app.UIAxes, mix4_channel1);
            
%             % 可以设置UIAxes的其他属性，比如标题、坐标轴标签等
%             title(app.UIAxes, '正弦波');
%             xlabel(app.UIAxes, '时间');
%             ylabel(app.UIAxes, '幅度');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %按下定制参数按钮，开始模型训练
            %导入训练集数据
            label=importdata("label_directions.mat");   %上\下\右\左\眨眼
            up=importdata("up_wavelet_features_1218.mat");
            down=importdata("down_wavelet_features_1218.mat");
            right=importdata("right_wavelet_features_1218.mat");
            left=importdata("left_wavelet_features_1218.mat");
            wink=importdata("wink_wavelet_features_1218.mat");
            dataset=[up;down;right;left;wink];
            dataset=real(dataset);      %数据集数据不能为复数
            
            %训练模型
            n=100;
            model = TreeBagger(n, dataset, label, Method="classification");
            view(model.Trees{n},'Mode','graph')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '眼电波形')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '采样窗口长度';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '定制参数';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '当前动作';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end